classdef P2V_En < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        P2VFigure               matlab.ui.Figure
        ButtonP2V               matlab.ui.control.Button
        ButtonV2P               matlab.ui.control.Button
        tag1                    matlab.ui.control.Button
        tag2                    matlab.ui.control.Button
        completeGaugeLabel      matlab.ui.control.Label
        completeGauge           matlab.ui.control.SemicircularGauge
        VPreview                matlab.ui.control.Image
        FrameRateLabel          matlab.ui.control.Label
        setFPS                  matlab.ui.control.NumericEditField
        PicPathLabel            matlab.ui.control.Label
        PicRoute                matlab.ui.control.EditField
        StatusLampLabel         matlab.ui.control.Label
        StatusLamp              matlab.ui.control.Lamp
        Button                  matlab.ui.control.Button
        FilenameLabel           matlab.ui.control.Label
        VideoName               matlab.ui.control.EditField
        FormatLabel             matlab.ui.control.Label
        PicFormat               matlab.ui.control.DropDown
        ConsistencycheckButton  matlab.ui.control.Button
        VideoInfo               matlab.ui.control.Label
        PictureandVideoConvertorbeta1Label  matlab.ui.control.Label
        Label                   matlab.ui.control.Label
    end

    
    properties (Access = private)
        Property % Description
        Pic_Route;
        Vi_Route;
        DefaultRoute;
        PicFmt;
        Vlen;
        allPics;
        Video_name;
        Video_path;
        UV;
        home;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.delete;
        end

        % Button pushed function: tag1
        function tag1ButtonPushed(app, event)

         len1=length(app.DefaultRoute);
         if len1==0
            app.Pic_Route = uigetdir('C:\');
         else
             app.Pic_Route=uigetdir(app.DefaultRoute);
         end %end of if len1 
          fnstrs=strsplit(app.Pic_Route,'\');
          currentfoldername=fnstrs{end};
          newvideoname=strcat(currentfoldername,'.avi');
          app.VideoName.Value=newvideoname;
          app.PicRoute.Value=app.Pic_Route;
          app.DefaultRoute=app.Pic_Route;
%          % len2=length(app.VideoRoute.Value);
%           if len2==0
%              app.VideoRoute.Value=app.DefaultRoute; 
%           end  %end of if len2
        app.PicFmt=strcat('*.',app.PicFormat.Value);
        cd(app.Pic_Route);
        app.allPics = struct2cell(dir(app.PicFmt));
        [~,len]=size(app.allPics);
        app.Vlen=len;
        % return the main window after dir chose
        drawnow;
        figure(app.P2VFigure);
          
        end

        % Button pushed function: tag2
        function tag2ButtonPushed(app, event)
         filterspec= {'*.avi;*.mp4;*.mpg;*.wmv;*.mov;*.m4v;*.mj2;*,asf;*.asx','All Video Files'};  
         [f, p] = uigetfile(filterspec,'Select a Video File');


             % Make sure user didn't cancel uigetfile dialog
           if (ischar(p))
               cd(p); 
               app.Video_name = [p f];
               app.Video_path=p;
           
           app.UV=VideoReader(app.Video_name); % create a video object
           %temppic=app.UV(:,:,:,1); %preview the first frame pic
           app.VPreview.ImageSource=read(app.UV,1);
                   % return the main window after dir chose

        %display video info
          l0str="Video parameters:";
          l1str=string(['TotalFrames:',num2str(app.UV.NumFrames)]);
          l2str=string(strcat('Framerate:',num2str(app.UV.FrameRate) ,' fps '));
          vinfo=l0str+newline+l1str+newline+l2str;
          app.VideoInfo.Text=vinfo;
          end
        drawnow;
        figure(app.P2VFigure);
        % cd PIC;
        end

        % Button pushed function: ButtonP2V
        function ButtonP2VPushed(app, event)
       %Vpath= app.VideoRoute.Value;
       %if given video file exist, just delete it.
        Vfname=app.VideoName.Value;
        if(exist('Vfname','file'))
            delete Vfname;
        end

        
        newVideo=VideoWriter(Vfname);
        newVideo.FrameRate=app.setFPS.Value;
        open(newVideo);
        app.StatusLamp.Color=[1,0,0];
        
        for i=1:app.Vlen
            app.completeGauge.Value=ceil((i/app.Vlen)*100);
            name=app.allPics{1,i};
            frame=imread(name);
            writeVideo(newVideo,frame);
        end % end of i-loop
        close(newVideo);
        app.StatusLamp.Color=[0,1,0];
        
        end

        % Button pushed function: ConsistencycheckButton
        function ConsistencycheckButtonPushed(app, event)
            Pwidth=zeros(1,app.Vlen);
            Pheight=zeros(1,app.Vlen);
           for i=1:app.Vlen
            name=app.allPics{1,i};
            frame=imread(name);
            Pwidth(i)=size(frame,1);
            Pheight(i)=size(frame,2);
           end % end of i-loop 
           Wdiff=sum(diff(Pwidth));
           Hdiff=sum(diff(Pheight));
           if (Wdiff || Hdiff)
               msgbox('序列中存在尺寸不一致的图片，请保证所有图片尺寸一致');
           else
                msgbox('恭喜!全部图片尺寸一致，可以开始转换');
           end
        
        end

        % Button pushed function: ButtonV2P
        function ButtonV2PPushed(app, event)
          i=1;
          app.home=pwd;
          cd(app.Video_path);
           try mkdir PIC
             
         catch 
             msgbox('无法创建图片保存文件夹，请检查文件夹访问权限');
          end
            while(hasFrame(app.UV))
                temp=readFrame(app.UV);
                str = strcat('./PIC/pic',int2str(i),'.jpg');
                imwrite(temp,str);
                i=i+1;
            end
            cd(app.home);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.P2VFigure = uifigure('Visible', 'off');
            app.P2VFigure.Name = 'PVconvertor';
            app.ButtonP2V = uibutton(app.P2VFigure, 'push');
            app.ButtonP2V.ButtonPushedFcn = createCallbackFcn(app, @ButtonP2VPushed, true);
            app.ButtonP2V.Text = '>>>';
            app.ButtonV2P = uibutton(app.P2VFigure, 'push');
            app.ButtonV2P.ButtonPushedFcn = createCallbackFcn(app, @ButtonV2PPushed, true);
            app.ButtonV2P.Text = '<<<';
            app.tag1 = uibutton(app.P2VFigure, 'push');
            app.tag1.ButtonPushedFcn = createCallbackFcn(app, @tag1ButtonPushed, true);
            app.tag1.Text = 'Images';
            app.tag2 = uibutton(app.P2VFigure, 'push');
            app.tag2.ButtonPushedFcn = createCallbackFcn(app, @tag2ButtonPushed, true);
            app.tag2.Text = 'Video File';
            app.completeGaugeLabel = uilabel(app.P2VFigure);
            app.completeGaugeLabel.Text = 'complete';
            app.completeGauge = uigauge(app.P2VFigure, 'semicircular');
            app.VPreview = uiimage(app.P2VFigure);
            app.FrameRateLabel = uilabel(app.P2VFigure);
            app.FrameRateLabel.Text = 'Frame Rate:';
            app.setFPS = uieditfield(app.P2VFigure, 'numeric');
            app.setFPS.Value = 10;
            app.PicPathLabel = uilabel(app.P2VFigure);
            app.PicPathLabel.Text = 'Pic Path:';
            app.PicRoute = uieditfield(app.P2VFigure, 'text');
            app.PicRoute.Value = 'C:\';
            app.StatusLampLabel = uilabel(app.P2VFigure);
            app.StatusLampLabel.Text = 'Status:';
            app.StatusLamp = uilamp(app.P2VFigure);
            app.Button = uibutton(app.P2VFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '❌';
            app.FilenameLabel = uilabel(app.P2VFigure);
            app.FilenameLabel.Text = 'Filename:';
            app.VideoName = uieditfield(app.P2VFigure, 'text');
            app.VideoName.Value = 'Default.avi';
            app.FormatLabel = uilabel(app.P2VFigure);
            app.FormatLabel.Text = 'Format';
            app.PicFormat = uidropdown(app.P2VFigure);
            app.PicFormat.Items = {'bmp', 'jpg', 'png', 'tif', 'gif'};
            app.PicFormat.Value = 'bmp';
            app.ConsistencycheckButton = uibutton(app.P2VFigure, 'push');
            app.ConsistencycheckButton.ButtonPushedFcn = createCallbackFcn(app, @ConsistencycheckButtonPushed, true);
            app.ConsistencycheckButton.Text = 'Consistency check';
            app.VideoInfo = uilabel(app.P2VFigure);
            app.VideoInfo.Text = 'No Video openned';
            app.PictureandVideoConvertorbeta1Label = uilabel(app.P2VFigure);
            app.PictureandVideoConvertorbeta1Label.Text = 'Picture and Video Convertor beta1';
            app.Label = uilabel(app.P2VFigure);
            app.Label.Text = {'1. click [Images] to choose an image folders,notice that all the'; '  images should  in the same resolution.(use Consistency check)'; '  to check if all images are in the same size.'; '2. click [>>>] to convert images into a video.'; 'OR  click [Video File] to choose a video file and then [<<<] to '; 'convert it to series of images, Default images will save to '; 'a sub-folder named <PIC> with video file.'};
            % Show the figure after all components are created
            app.P2VFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = P2V_En

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.P2VFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.P2VFigure)
        end
    end
end